classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SynthesizerPanel          matlab.ui.container.Panel
        Button5_18                matlab.ui.control.Button
        Button5_17                matlab.ui.control.Button
        Button5_16                matlab.ui.control.Button
        Button5_15                matlab.ui.control.Button
        Button5_14                matlab.ui.control.Button
        Button_24                 matlab.ui.control.Button
        Button_23                 matlab.ui.control.Button
        Button_22                 matlab.ui.control.Button
        Button_21                 matlab.ui.control.Button
        Button_20                 matlab.ui.control.Button
        Button_19                 matlab.ui.control.Button
        Button_18                 matlab.ui.control.Button
        Button5_13                matlab.ui.control.Button
        Button5_12                matlab.ui.control.Button
        Button5_11                matlab.ui.control.Button
        Button5_10                matlab.ui.control.Button
        Button5_9                 matlab.ui.control.Button
        Button_17                 matlab.ui.control.Button
        Button_13                 matlab.ui.control.Button
        Button_12                 matlab.ui.control.Button
        Button_11                 matlab.ui.control.Button
        Button_16                 matlab.ui.control.Button
        Button_15                 matlab.ui.control.Button
        Button_14                 matlab.ui.control.Button
        ButtonGroupPanel          matlab.ui.container.Panel
        AMwaveCheckBox            matlab.ui.control.CheckBox
        SawtoothwaveCheckBox      matlab.ui.control.CheckBox
        SquarewaveCheckBox        matlab.ui.control.CheckBox
        SinewaveCheckBox          matlab.ui.control.CheckBox
        SaveButton_3              matlab.ui.control.Button
        SampleNoDropDown_3        matlab.ui.control.DropDown
        SampleNoDropDown_3Label   matlab.ui.control.Label
        UIAxes2                   matlab.ui.control.UIAxes
        ChoppingPanel             matlab.ui.container.Panel
        EndSlider                 matlab.ui.control.Slider
        EndSliderLabel            matlab.ui.control.Label
        StartSlider               matlab.ui.control.Slider
        StartSliderLabel          matlab.ui.control.Label
        SaveButton_2              matlab.ui.control.Button
        SampleNoDropDown_2        matlab.ui.control.DropDown
        SampleNoDropDown_2Label   matlab.ui.control.Label
        EffectsandSampleModificationPanel  matlab.ui.container.Panel
        DelayLabel                matlab.ui.control.Label
        DelayTimeKnob             matlab.ui.control.Knob
        DelayTimeKnobLabel        matlab.ui.control.Label
        PlaybackDirectionLabel    matlab.ui.control.Label
        kHzSlider_2               matlab.ui.control.Slider
        kHzSlider_2Label          matlab.ui.control.Label
        kHzSlider                 matlab.ui.control.Slider
        kHzSliderLabel            matlab.ui.control.Label
        HzSlider_4                matlab.ui.control.Slider
        HzLabel_4                 matlab.ui.control.Label
        HzSlider_3                matlab.ui.control.Slider
        HzLabel_3                 matlab.ui.control.Label
        HzSlider_2                matlab.ui.control.Slider
        HzLabel_2                 matlab.ui.control.Label
        HzSlider                  matlab.ui.control.Slider
        HzLabel                   matlab.ui.control.Label
        FeedbackLevelKnob         matlab.ui.control.Knob
        FeedbackLevelKnobLabel    matlab.ui.control.Label
        PlaybackSpeedSlider       matlab.ui.control.Slider
        PlaybackSpeedSliderLabel  matlab.ui.control.Label
        Switch                    matlab.ui.control.Switch
        VolumeKnob                matlab.ui.control.Knob
        VolumeKnobLabel           matlab.ui.control.Label
        SaveButton                matlab.ui.control.Button
        SampleNoDropDown          matlab.ui.control.DropDown
        SampleNoDropDownLabel     matlab.ui.control.Label
        SampleSelectionPanel      matlab.ui.container.Panel
        sampleSelectionMessage    matlab.ui.control.Label
        Load9Button               matlab.ui.control.Button
        Sample9Button             matlab.ui.control.Button
        Load8Button               matlab.ui.control.Button
        Sample8Button             matlab.ui.control.Button
        Load7Button               matlab.ui.control.Button
        Sample7Button             matlab.ui.control.Button
        Load6Button               matlab.ui.control.Button
        Sample6Button             matlab.ui.control.Button
        Load4Button               matlab.ui.control.Button
        Sample4Button             matlab.ui.control.Button
        Load5Button               matlab.ui.control.Button
        Sample5Button             matlab.ui.control.Button
        Load3Button               matlab.ui.control.Button
        Load2Button               matlab.ui.control.Button
        Load1Button               matlab.ui.control.Button
        Sample3Button             matlab.ui.control.Button
        Sample2Button             matlab.ui.control.Button
        Sample1Button             matlab.ui.control.Button
        sampleSoundWaveAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        samplePathArr = strings([1, 9]); % Description
        playerArr = {};
        playFlagArr = zeros(1, 9);
    end
    
    methods (Access = private)
        
        function loadSample(app, idx)
            [file, path] = uigetfile('*.wav');
            if isequal(file,0)
                app.samepleSelectionMessage.Text = "User selected Cancel";
            else
                file = convertCharsToStrings(file);
                path = convertCharsToStrings(path);
                filepath = path + file;
                app.sampleSelectionMessage.Text = "User selected " + newline + filepath;
                app.samplePathArr(idx) = filepath;
            end
        end
        
        function playPauseAudio(app, idx)
            if((app.samplePathArr(idx)) == "")
                app.sampleSelectionMessage.Text = "Please load file first.";
                return;
            end
            
            
            filename = app.samplePathArr(idx);
            [audioData, sampleRate] = audioread(filename);
            t = (0:length(audioData) - 1) / sampleRate;
            plot(app.sampleSoundWaveAxes, t, audioData);
            app.playerArr{idx} = audioplayer(audioData, sampleRate);
            if app.playFlagArr(idx) == 0
                try
                    % Play audio using audioplayer
                    resume(app.playerArr{idx}); 
                    app.playFlagArr(idx) = 1;
                catch
                    app.sampleSelectionMessage.Text = "Error playing audio file.";
                end 
            else
                pause(app.playerArr{idx});
                app.playFlagArr(idx) = 0;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Sample1Button
        function Sample1ButtonPushed(app, event)
            app.playPauseAudio(1);
        end

        % Button pushed function: Load1Button
        function Load1ButtonPushed(app, event)
            app.loadSample(1);
        end

        % Button pushed function: Sample2Button
        function Sample2ButtonPushed(app, event)
            app.playPauseAudio(2);
        end

        % Button pushed function: Load2Button
        function Load2ButtonPushed(app, event)
            app.loadSample(2);
        end

        % Button pushed function: Sample3Button
        function Sample3ButtonPushed(app, event)
            app.playPauseAudio(3);
        end

        % Button pushed function: Load3Button
        function Load3ButtonPushed(app, event)
            app.loadSample(3);
        end

        % Button pushed function: Load4Button
        function Load4ButtonPushed(app, event)
            app.loadSample(4);
        end

        % Button pushed function: Sample4Button
        function Sample4ButtonPushed(app, event)
            app.playPauseAudio(4);
        end

        % Button pushed function: Load5Button
        function Load5ButtonPushed(app, event)
            app.loadSample(5);
        end

        % Button pushed function: Sample5Button
        function Sample5ButtonPushed(app, event)
            app.playPauseAudio(5);
        end

        % Button pushed function: Sample6Button
        function Sample6ButtonPushed(app, event)
            app.playPauseAudio(6);
        end

        % Button pushed function: Load6Button
        function Load6ButtonPushed(app, event)
            app.loadSample(6);
        end

        % Button pushed function: Sample7Button
        function Sample7ButtonPushed(app, event)
            app.playPauseAudio(7);
        end

        % Button pushed function: Load7Button
        function Load7ButtonPushed(app, event)
            app.loadSample(7);
        end

        % Button pushed function: Sample8Button
        function Sample8ButtonPushed(app, event)
            app.playPauseAudio(8);
        end

        % Button pushed function: Load8Button
        function Load8ButtonPushed(app, event)
            app.loadSample(8);
        end

        % Button pushed function: Sample9Button
        function Sample9ButtonPushed(app, event)
            app.playPauseAudio(9);
        end

        % Button pushed function: Load9Button
        function Load9ButtonPushed(app, event)
            app.loadSample(9);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.sampleSoundWaveAxes = uiaxes(app.UIFigure);
            title(app.sampleSoundWaveAxes, 'Soundwave')
            xlabel(app.sampleSoundWaveAxes, 'Time(s)')
            ylabel(app.sampleSoundWaveAxes, 'Amplitude')
            zlabel(app.sampleSoundWaveAxes, 'Z')
            app.SampleSelectionPanel = uipanel(app.UIFigure);
            app.SampleSelectionPanel.Title = 'Sample Selection';
            app.Sample1Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample1Button.ButtonPushedFcn = createCallbackFcn(app, @Sample1ButtonPushed, true);
            app.Sample1Button.Text = 'Sample1';
            app.Sample2Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample2Button.ButtonPushedFcn = createCallbackFcn(app, @Sample2ButtonPushed, true);
            app.Sample2Button.Text = 'Sample2';
            app.Sample3Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample3Button.ButtonPushedFcn = createCallbackFcn(app, @Sample3ButtonPushed, true);
            app.Sample3Button.Text = 'Sample3';
            app.Load1Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Load1Button.ButtonPushedFcn = createCallbackFcn(app, @Load1ButtonPushed, true);
            app.Load1Button.Text = 'Load1';
            app.Load2Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Load2Button.ButtonPushedFcn = createCallbackFcn(app, @Load2ButtonPushed, true);
            app.Load2Button.Text = 'Load2';
            app.Load3Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Load3Button.ButtonPushedFcn = createCallbackFcn(app, @Load3ButtonPushed, true);
            app.Load3Button.Text = 'Load3';
            app.Sample5Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample5Button.ButtonPushedFcn = createCallbackFcn(app, @Sample5ButtonPushed, true);
            app.Sample5Button.Text = 'Sample5';
            app.Load5Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Load5Button.ButtonPushedFcn = createCallbackFcn(app, @Load5ButtonPushed, true);
            app.Load5Button.Text = 'Load5';
            app.Sample4Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample4Button.ButtonPushedFcn = createCallbackFcn(app, @Sample4ButtonPushed, true);
            app.Sample4Button.Text = 'Sample4';
            app.Load4Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Load4Button.ButtonPushedFcn = createCallbackFcn(app, @Load4ButtonPushed, true);
            app.Load4Button.Text = 'Load4';
            app.Sample6Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample6Button.ButtonPushedFcn = createCallbackFcn(app, @Sample6ButtonPushed, true);
            app.Sample6Button.Text = 'Sample6';
            app.Load6Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Load6Button.ButtonPushedFcn = createCallbackFcn(app, @Load6ButtonPushed, true);
            app.Load6Button.Text = 'Load6';
            app.Sample7Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample7Button.ButtonPushedFcn = createCallbackFcn(app, @Sample7ButtonPushed, true);
            app.Sample7Button.Text = 'Sample7';
            app.Load7Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Load7Button.ButtonPushedFcn = createCallbackFcn(app, @Load7ButtonPushed, true);
            app.Load7Button.Text = 'Load7';
            app.Sample8Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample8Button.ButtonPushedFcn = createCallbackFcn(app, @Sample8ButtonPushed, true);
            app.Sample8Button.Text = 'Sample8';
            app.Load8Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Load8Button.ButtonPushedFcn = createCallbackFcn(app, @Load8ButtonPushed, true);
            app.Load8Button.Text = 'Load8';
            app.Sample9Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample9Button.ButtonPushedFcn = createCallbackFcn(app, @Sample9ButtonPushed, true);
            app.Sample9Button.Text = 'Sample9';
            app.Load9Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Load9Button.ButtonPushedFcn = createCallbackFcn(app, @Load9ButtonPushed, true);
            app.Load9Button.Text = 'Load9';
            app.sampleSelectionMessage = uilabel(app.SampleSelectionPanel);
            app.sampleSelectionMessage.Text = '';
            app.EffectsandSampleModificationPanel = uipanel(app.UIFigure);
            app.EffectsandSampleModificationPanel.Title = 'Effects and Sample Modification';
            app.SampleNoDropDownLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.SampleNoDropDownLabel.Text = 'Sample No';
            app.SampleNoDropDown = uidropdown(app.EffectsandSampleModificationPanel);
            app.SaveButton = uibutton(app.EffectsandSampleModificationPanel, 'push');
            app.SaveButton.Text = 'Save';
            app.VolumeKnobLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.VolumeKnobLabel.Text = 'Volume  ';
            app.VolumeKnob = uiknob(app.EffectsandSampleModificationPanel, 'continuous');
            app.Switch = uiswitch(app.EffectsandSampleModificationPanel, 'slider');
            app.Switch.Items = {'Backward', 'Forward'};
            app.Switch.Value = 'Backward';
            app.PlaybackSpeedSliderLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.PlaybackSpeedSliderLabel.Text = 'Playback Speed';
            app.PlaybackSpeedSlider = uislider(app.EffectsandSampleModificationPanel);
            app.FeedbackLevelKnobLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.FeedbackLevelKnobLabel.Text = 'Feedback Level';
            app.FeedbackLevelKnob = uiknob(app.EffectsandSampleModificationPanel, 'continuous');
            app.HzLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.HzLabel.Text = '63 Hz';
            app.HzSlider = uislider(app.EffectsandSampleModificationPanel);
            app.HzLabel_2 = uilabel(app.EffectsandSampleModificationPanel);
            app.HzLabel_2.Text = '125 Hz';
            app.HzSlider_2 = uislider(app.EffectsandSampleModificationPanel);
            app.HzLabel_3 = uilabel(app.EffectsandSampleModificationPanel);
            app.HzLabel_3.Text = '250 Hz';
            app.HzSlider_3 = uislider(app.EffectsandSampleModificationPanel);
            app.HzLabel_4 = uilabel(app.EffectsandSampleModificationPanel);
            app.HzLabel_4.Text = '500 Hz';
            app.HzSlider_4 = uislider(app.EffectsandSampleModificationPanel);
            app.kHzSliderLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.kHzSliderLabel.Text = '1k Hz';
            app.kHzSlider = uislider(app.EffectsandSampleModificationPanel);
            app.kHzSlider_2Label = uilabel(app.EffectsandSampleModificationPanel);
            app.kHzSlider_2Label.Text = '2kHz';
            app.kHzSlider_2 = uislider(app.EffectsandSampleModificationPanel);
            app.PlaybackDirectionLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.PlaybackDirectionLabel.Text = 'Playback Direction';
            app.DelayTimeKnobLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.DelayTimeKnobLabel.Text = 'Delay Time';
            app.DelayTimeKnob = uiknob(app.EffectsandSampleModificationPanel, 'continuous');
            app.DelayLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.DelayLabel.Text = 'Delay';
            app.ChoppingPanel = uipanel(app.UIFigure);
            app.ChoppingPanel.Title = 'Chopping';
            app.SampleNoDropDown_2Label = uilabel(app.ChoppingPanel);
            app.SampleNoDropDown_2Label.Text = 'Sample No';
            app.SampleNoDropDown_2 = uidropdown(app.ChoppingPanel);
            app.SaveButton_2 = uibutton(app.ChoppingPanel, 'push');
            app.SaveButton_2.Text = 'Save';
            app.StartSliderLabel = uilabel(app.ChoppingPanel);
            app.StartSliderLabel.Text = 'Start';
            app.StartSlider = uislider(app.ChoppingPanel);
            app.EndSliderLabel = uilabel(app.ChoppingPanel);
            app.EndSliderLabel.Text = 'End';
            app.EndSlider = uislider(app.ChoppingPanel);
            app.SynthesizerPanel = uipanel(app.UIFigure);
            app.SynthesizerPanel.Title = 'Synthesizer';
            app.UIAxes2 = uiaxes(app.SynthesizerPanel);
            title(app.UIAxes2, 'Soundwave')
            xlabel(app.UIAxes2, 'Time(s)')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.SampleNoDropDown_3Label = uilabel(app.SynthesizerPanel);
            app.SampleNoDropDown_3Label.Text = 'Sample No';
            app.SampleNoDropDown_3 = uidropdown(app.SynthesizerPanel);
            app.SaveButton_3 = uibutton(app.SynthesizerPanel, 'push');
            app.SaveButton_3.Text = 'Save';
            app.ButtonGroupPanel = uipanel(app.SynthesizerPanel);
            app.ButtonGroupPanel.Title = 'Button Group';
            app.SinewaveCheckBox = uicheckbox(app.ButtonGroupPanel);
            app.SinewaveCheckBox.Text = 'Sine wave';
            app.SquarewaveCheckBox = uicheckbox(app.ButtonGroupPanel);
            app.SquarewaveCheckBox.Text = 'Square wave';
            app.SawtoothwaveCheckBox = uicheckbox(app.ButtonGroupPanel);
            app.SawtoothwaveCheckBox.Text = 'Sawtooth wave';
            app.AMwaveCheckBox = uicheckbox(app.ButtonGroupPanel);
            app.AMwaveCheckBox.Text = 'AM wave';
            app.Button_14 = uibutton(app.SynthesizerPanel, 'push');
            app.Button_14.Text = '';
            app.Button_15 = uibutton(app.SynthesizerPanel, 'push');
            app.Button_15.Text = '';
            app.Button_16 = uibutton(app.SynthesizerPanel, 'push');
            app.Button_16.Text = '';
            app.Button_11 = uibutton(app.SynthesizerPanel, 'push');
            app.Button_11.Text = '';
            app.Button_12 = uibutton(app.SynthesizerPanel, 'push');
            app.Button_12.Text = '';
            app.Button_13 = uibutton(app.SynthesizerPanel, 'push');
            app.Button_13.Text = '';
            app.Button_17 = uibutton(app.SynthesizerPanel, 'push');
            app.Button_17.Text = '';
            app.Button5_9 = uibutton(app.SynthesizerPanel, 'push');
            app.Button5_9.Text = 'Button5';
            app.Button5_10 = uibutton(app.SynthesizerPanel, 'push');
            app.Button5_10.Text = 'Button5';
            app.Button5_11 = uibutton(app.SynthesizerPanel, 'push');
            app.Button5_11.Text = 'Button5';
            app.Button5_12 = uibutton(app.SynthesizerPanel, 'push');
            app.Button5_12.Text = 'Button5';
            app.Button5_13 = uibutton(app.SynthesizerPanel, 'push');
            app.Button5_13.Text = 'Button5';
            app.Button_18 = uibutton(app.SynthesizerPanel, 'push');
            app.Button_18.Text = '';
            app.Button_19 = uibutton(app.SynthesizerPanel, 'push');
            app.Button_19.Text = '';
            app.Button_20 = uibutton(app.SynthesizerPanel, 'push');
            app.Button_20.Text = '';
            app.Button_21 = uibutton(app.SynthesizerPanel, 'push');
            app.Button_21.Text = '';
            app.Button_22 = uibutton(app.SynthesizerPanel, 'push');
            app.Button_22.Text = '';
            app.Button_23 = uibutton(app.SynthesizerPanel, 'push');
            app.Button_23.Text = '';
            app.Button_24 = uibutton(app.SynthesizerPanel, 'push');
            app.Button_24.Text = '';
            app.Button5_14 = uibutton(app.SynthesizerPanel, 'push');
            app.Button5_14.Text = 'Button5';
            app.Button5_15 = uibutton(app.SynthesizerPanel, 'push');
            app.Button5_15.Text = 'Button5';
            app.Button5_16 = uibutton(app.SynthesizerPanel, 'push');
            app.Button5_16.Text = 'Button5';
            app.Button5_17 = uibutton(app.SynthesizerPanel, 'push');
            app.Button5_17.Text = 'Button5';
            app.Button5_18 = uibutton(app.SynthesizerPanel, 'push');
            app.Button5_18.Text = 'Button5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end